classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MapVisualizationTab            matlab.ui.container.Tab
        statPanel                      matlab.ui.container.Panel
        statHeader                     matlab.ui.control.Label
        ConfirmedLabel                 matlab.ui.control.Label
        ConfirmedNum                   matlab.ui.control.Label
        DeathsNum                      matlab.ui.control.Label
        DeathsLabel                    matlab.ui.control.Label
        ActiveNum                      matlab.ui.control.Label
        ActiveLabel                    matlab.ui.control.Label
        RecoveredNum                   matlab.ui.control.Label
        RecoveredLabel                 matlab.ui.control.Label
        DATELabel                      matlab.ui.control.Label
        AsofLabel_2                    matlab.ui.control.Label
        mapLegend                      matlab.ui.container.Panel
        headerLabel_2                  matlab.ui.control.Label
        ColorSwitchLabel               matlab.ui.control.Label
        ColorSwitch                    matlab.ui.control.Switch
        BasemapDropDownLabel           matlab.ui.control.Label
        BasemapDropDown                matlab.ui.control.DropDown
        DateDatePickerLabel            matlab.ui.control.Label
        DatePicker                     matlab.ui.control.DatePicker
        resetButton                    matlab.ui.control.Button
        saveimageButton                matlab.ui.control.Button
        mapPanel                       matlab.ui.container.Panel
        GlobalCumulativeCOVID19CasesLabel  matlab.ui.control.Label
        GraphTab                       matlab.ui.container.Tab
        graphAxes                      matlab.ui.control.UIAxes
        topCountriesLabel              matlab.ui.control.Label
        topCountries                   matlab.ui.control.Spinner
        topCountriesLabel2             matlab.ui.control.Label
        AsofDatePickerLabel            matlab.ui.control.Label
        AsofDatePicker                 matlab.ui.control.DatePicker
        DaySliderLabel                 matlab.ui.control.Label
        DaySlider                      matlab.ui.control.Slider
        CumulativeCasesbyCountryLabel  matlab.ui.control.Label
        TimelapseTab                   matlab.ui.container.Tab
        timelapseAxes                  matlab.ui.control.UIAxes
        SaveImageLabel                 matlab.ui.control.Label
        saveImage                      matlab.ui.control.Switch
        TimelapsetheProgressionofthePandemicLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        CountriesSpinnerLabel          matlab.ui.control.Label
        nC                             matlab.ui.control.Spinner
        timelapseDate                  matlab.ui.control.Label
        timelapseDate2                 matlab.ui.control.Label
        PLAYLabel                      matlab.ui.control.Label
        OthersTab                      matlab.ui.container.Tab
        MainMenuButton                 matlab.ui.control.Button
        JohnsHopkinsUniversityCSSECOVID19DataRepositoryLabel  matlab.ui.control.Label
        DatabaseLabel                  matlab.ui.control.Label
        LastRetrievedLabel             matlab.ui.control.Label
        TimestampLabel                 matlab.ui.control.Label
    end

    % PUBLIC properties that can be accessed publicly in app
    properties (Access = public)
        dates
        countries
        lat
        long
        confirmed
        deaths
        recov
        globalCC
        fullGlobal
        fullUS
        cases
        gb
        newdate
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % center app window
            movegui(app.UIFigure,"center");
            
            % load data from downloads
            loadData(app);
            
            %% MAP VISUALIZATION TAB
            % initialize date picker
            app.newdate = datetime(app.dates(end),'InputFormat','M/d/yy','Format','MMM-dd-yyyy');
            app.DatePicker.Limits = [datetime([2020 1 22]) app.newdate];
            
            % initialize map plot variables
            app.gb.Basemap = 'streets-dark';
            app.gb.BubbleColorList = parula(10);
            plotMap(app);  % plot map
            
            
            %% GRAPH TAB
            % initialize date picker and slider
            app.AsofDatePicker.Limits = [datetime([2020 1 22]) app.newdate];
            app.DaySlider.Limits = [1,length(app.dates)];
            app.DaySlider.Value = length(app.dates);
            
            % axes and plot properties
            colormap(app.graphAxes,parula);
            axis(app.graphAxes,'tight');
            
            plotCountry(app);   % plot graph
            
            
            %% TIMELAPSE TAB
            app.timelapseAxes.YAxis.Direction = 'reverse';
            app.timelapseAxes.XTickLabelMode = 'auto';
            barChartAnimation(app,0);   % set up bar chart
            
            
            %% OTHERS TAB
            d = dir('data/COVID-19-master/csse_covid_19_data');
            app.TimestampLabel.Text = d.date;   % data retrieval date
            
            
        end

        % Value changed function: ColorSwitch
        function ColorSwitchValueChanged(app, event)
            % When map color is switched on/off
            
            value = app.ColorSwitch.Value;
            
            % if color switch = on, set color data
            if strcmp(value,'On')
                app.gb.BubbleColorList = parula(10);
                app.gb.ColorData = discretize(app.cases,10,'categorical');
                
            % if color switch = off, remove color
            elseif strcmp(value,'Off')
                app.gb.ColorData = [];
                app.gb.BubbleColorList = gray;
            end
            
            
        end

        % Value changed function: BasemapDropDown
        function BasemapDropDownValueChanged(app, event)
            % When basemap dropdown selection is changed:
            value = app.BasemapDropDown.Value;
            
            % change basemap based on selection
            if strcmp(value,'Dark')
                app.gb.Basemap = 'streets-dark';
            elseif strcmp(value,'Gray')
                app.gb.Basemap = 'grayland';
            elseif strcmp(value,'Terrain')
                app.gb.Basemap = 'colorterrain';
            end
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            % When date picker is changed
            value = app.DatePicker.Value;
            
            % convert value to datetime in specific format
            app.newdate = datetime(value,'Format','MMM-dd-yyyy');
            
            % update map
            plotMap(app);
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            % When reset button is pushed:
            
            % Reset basemap, date
            app.gb.Basemap = 'streets-dark';
            app.newdate = datetime(app.dates(end),'InputFormat','M/d/yy','Format','MMM-dd-yyyy');
            plotMap(app);   % replot map
        end

        % Value changed function: DaySlider
        function DaySliderValueChanged(app, event)
            % Update plot when Day number is changed on slider
            plotCountry(app);   
        end

        % Value changed function: topCountries
        function topCountriesValueChanged(app, event)
            % Update plot when number of countries to display changes
            plotCountry(app);   
        end

        % Value changed function: AsofDatePicker
        function AsofDatePickerValueChanged(app, event)
            % When date picker changes:
            value = app.AsofDatePicker.Value;
            
            % update day slider to match
            value.Format = 'M/d/yy';
            index = find(value == app.dates);
            app.DaySlider.Value = index;
            
            % update plot
            plotCountry(app);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Play animation when play icon pressed
            barChartAnimation(app,1);
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            % When return to main menu button pressed, close main app and
            % open menu.mlapp
            delete(app);
            menu;
        end

        % Button pushed function: saveimageButton
        function saveimageButtonPushed(app, event)
            % When save image is pressed (on map visualization), save map
            % as 'COVID19_Map.jpg' at 300 dpi
            exportgraphics(app.mapPanel,'COVID19_Map.jpg','Resolution',300);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COVID-19 Pandemic';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MapVisualizationTab = uitab(app.TabGroup);
            app.MapVisualizationTab.Title = 'Map Visualization';
            app.statPanel = uipanel(app.MapVisualizationTab);
            app.statHeader = uilabel(app.statPanel);
            app.statHeader.Text = 'Global Statistics';
            app.ConfirmedLabel = uilabel(app.statPanel);
            app.ConfirmedLabel.Text = 'Confirmed';
            app.ConfirmedNum = uilabel(app.statPanel);
            app.ConfirmedNum.Text = '[         ]';
            app.DeathsNum = uilabel(app.statPanel);
            app.DeathsNum.Text = '[         ]';
            app.DeathsLabel = uilabel(app.statPanel);
            app.DeathsLabel.Text = 'Deaths';
            app.ActiveNum = uilabel(app.statPanel);
            app.ActiveNum.Text = '[         ]';
            app.ActiveLabel = uilabel(app.statPanel);
            app.ActiveLabel.Text = 'Active';
            app.RecoveredNum = uilabel(app.statPanel);
            app.RecoveredNum.Text = '[         ]';
            app.RecoveredLabel = uilabel(app.statPanel);
            app.RecoveredLabel.Text = 'Recovered';
            app.DATELabel = uilabel(app.statPanel);
            app.DATELabel.Text = '[DATE]';
            app.AsofLabel_2 = uilabel(app.statPanel);
            app.AsofLabel_2.Text = 'As of';
            app.mapLegend = uipanel(app.MapVisualizationTab);
            app.headerLabel_2 = uilabel(app.mapLegend);
            app.headerLabel_2.Tooltip = {'HELLO'};
            app.headerLabel_2.Text = 'Map Settings';
            app.ColorSwitchLabel = uilabel(app.mapLegend);
            app.ColorSwitchLabel.Text = 'Color';
            app.ColorSwitch = uiswitch(app.mapLegend, 'slider');
            app.ColorSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorSwitchValueChanged, true);
            app.ColorSwitch.Value = 'On';
            app.BasemapDropDownLabel = uilabel(app.mapLegend);
            app.BasemapDropDownLabel.Text = 'Basemap';
            app.BasemapDropDown = uidropdown(app.mapLegend);
            app.BasemapDropDown.Items = {'Dark', 'Gray', 'Terrain'};
            app.BasemapDropDown.ValueChangedFcn = createCallbackFcn(app, @BasemapDropDownValueChanged, true);
            app.BasemapDropDown.Value = 'Dark';
            app.DateDatePickerLabel = uilabel(app.mapLegend);
            app.DateDatePickerLabel.Text = 'Date';
            app.DatePicker = uidatepicker(app.mapLegend);
            app.DatePicker.Limits = [datetime([2020 1 22]) datetime([9999 12 31])];
            app.DatePicker.DisplayFormat = 'MM-dd-yyyy';
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.resetButton = uibutton(app.mapLegend, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.saveimageButton = uibutton(app.mapLegend, 'push');
            app.saveimageButton.ButtonPushedFcn = createCallbackFcn(app, @saveimageButtonPushed, true);
            app.saveimageButton.Text = 'save image';
            app.mapPanel = uipanel(app.MapVisualizationTab);
            app.GlobalCumulativeCOVID19CasesLabel = uilabel(app.MapVisualizationTab);
            app.GlobalCumulativeCOVID19CasesLabel.Text = 'Global Cumulative COVID-19 Cases  ';
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.graphAxes = uiaxes(app.GraphTab);
            title(app.graphAxes, '')
            xlabel(app.graphAxes, 'Day')
            ylabel(app.graphAxes, 'Total Cases')
            app.graphAxes.ZTick = [];
            app.topCountriesLabel = uilabel(app.GraphTab);
            app.topCountriesLabel.Text = 'View top';
            app.topCountries = uispinner(app.GraphTab);
            app.topCountries.Limits = [1 15];
            app.topCountries.ValueChangedFcn = createCallbackFcn(app, @topCountriesValueChanged, true);
            app.topCountries.Value = 10;
            app.topCountriesLabel2 = uilabel(app.GraphTab);
            app.topCountriesLabel2.Text = 'countries';
            app.AsofDatePickerLabel = uilabel(app.GraphTab);
            app.AsofDatePickerLabel.Text = 'As of:';
            app.AsofDatePicker = uidatepicker(app.GraphTab);
            app.AsofDatePicker.ValueChangedFcn = createCallbackFcn(app, @AsofDatePickerValueChanged, true);
            app.DaySliderLabel = uilabel(app.GraphTab);
            app.DaySliderLabel.Text = 'Day';
            app.DaySlider = uislider(app.GraphTab);
            app.DaySlider.ValueChangedFcn = createCallbackFcn(app, @DaySliderValueChanged, true);
            app.DaySlider.MinorTicks = [];
            app.DaySlider.Tooltip = {''};
            app.DaySlider.Value = 100;
            app.CumulativeCasesbyCountryLabel = uilabel(app.GraphTab);
            app.CumulativeCasesbyCountryLabel.Text = 'Cumulative Cases by Country';
            app.TimelapseTab = uitab(app.TabGroup);
            app.TimelapseTab.Title = 'Timelapse';
            app.timelapseAxes = uiaxes(app.TimelapseTab);
            title(app.timelapseAxes, '')
            xlabel(app.timelapseAxes, '# of Confirmed Cases')
            ylabel(app.timelapseAxes, 'Country')
            app.timelapseAxes.XTick = [];
            app.timelapseAxes.XTickLabel = '';
            app.timelapseAxes.YTick = [];
            app.timelapseAxes.YTickLabel = '';
            app.SaveImageLabel = uilabel(app.TimelapseTab);
            app.SaveImageLabel.Tooltip = {'Save completed image as jpg'};
            app.SaveImageLabel.Text = 'Save Image';
            app.saveImage = uiswitch(app.TimelapseTab, 'slider');
            app.TimelapsetheProgressionofthePandemicLabel = uilabel(app.TimelapseTab);
            app.TimelapsetheProgressionofthePandemicLabel.Text = 'Timelapse: the Progression of the Pandemic';
            app.Image = uiimage(app.TimelapseTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Tooltip = {'Click to play animation!'};
            app.Image.ImageSource = 'play.png';
            app.CountriesSpinnerLabel = uilabel(app.TimelapseTab);
            app.CountriesSpinnerLabel.Tooltip = {'Select number of countries to display'};
            app.CountriesSpinnerLabel.Text = '# Countries';
            app.nC = uispinner(app.TimelapseTab);
            app.nC.Limits = [1 20];
            app.nC.Value = 10;
            app.timelapseDate = uilabel(app.TimelapseTab);
            app.timelapseDate.Text = 'DAY 1';
            app.timelapseDate2 = uilabel(app.TimelapseTab);
            app.timelapseDate2.Text = '22-Jan-2020';
            app.PLAYLabel = uilabel(app.TimelapseTab);
            app.PLAYLabel.Text = 'PLAY';
            app.OthersTab = uitab(app.TabGroup);
            app.OthersTab.Title = 'Others';
            app.MainMenuButton = uibutton(app.OthersTab, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Icon = 'return.png';
            app.MainMenuButton.Text = {'   Main Menu'; ''};
            app.JohnsHopkinsUniversityCSSECOVID19DataRepositoryLabel = uilabel(app.OthersTab);
            app.JohnsHopkinsUniversityCSSECOVID19DataRepositoryLabel.Text = {''; 'Johns Hopkins University CSSE COVID-19 Data Repository'};
            app.DatabaseLabel = uilabel(app.OthersTab);
            app.DatabaseLabel.Text = {'Database: '; ''};
            app.LastRetrievedLabel = uilabel(app.OthersTab);
            app.LastRetrievedLabel.Text = 'Last Retrieved:';
            app.TimestampLabel = uilabel(app.OthersTab);
            app.TimestampLabel.Text = 'Timestamp';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end