classdef menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        title                     matlab.ui.control.Label
        UseexistingdataButton     matlab.ui.control.StateButton
        DownloadlatestdataButton  matlab.ui.control.StateButton
        Image                     matlab.ui.control.Image
        NoexistingdataLabel       matlab.ui.control.Label
        DATELabel                 matlab.ui.control.Label
    end

    % Private properties 
    properties (Access = private)
        retrievalDate
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % center app window
            movegui(app.UIFigure,"center");
            
            % display current date
            t = datetime('today');
            t.Format = 'MMM dd, yyyy';
            app.DATELabel.Text = string(t);
            
            % display date of last data retrieval
            if exist('data/COVID-19-master/csse_covid_19_data')
                d = dir('data/COVID-19-master/csse_covid_19_data/csse_covid_19_daily_reports/');
                app.retrievalDate = d.date;
                app.NoexistingdataLabel.Text = ['Data Last Retrieved:  ',app.retrievalDate];
            end
        end

        % Value changed function: UseexistingdataButton
        function useExistingData(app, event)
            % When 'use existing data' button is pressed:
            
            % Error message if no existing data
            if ~exist('data/COVID-19-master/csse_covid_19_data')
                errordlg('Data not found, please download latest data','No existing data');
            % Otherwise close app and open main.mlapp
            else
                delete(app);
                main;
            end
        end

        % Value changed function: DownloadlatestdataButton
        function updateData(app, event)
            % When 'download latest data' button is pressed:
            
            % if data in directory is the most current version
            if exist('data/COVID-19-master') & app.retrievalDate(1:11) == date
                
                % question dialogue box to proceed
                answer = questdlg('You already have the most updated data', ...
                    '','Proceed','Go back');
                switch answer
                    case 'Proceed'
                        delete(app);
                        main;
                end
                
                % if data is not updated or no existing data
            else
                % change button name to "Retrieving data..."
                app.DownloadlatestdataButton.Text = 'Retrieving data...';
                app.DownloadlatestdataButton.Tooltip = {'This may take a minute, please hold'};
                
                % remove current data
                if exist('data/COVID-19-master')
                    rmdir('data','s');
                end
                
                % change button to downloading waitbar icon
                app.DownloadlatestdataButton.BackgroundColor = [1 1 1];
                app.DownloadlatestdataButton.FontColor = [.15 .15 .15];
                app.DownloadlatestdataButton.Position = [338 173 128 40];
                app.DownloadlatestdataButton.IconAlignment = 'bottom';
                wbar = permute(repmat(app.DownloadlatestdataButton.BackgroundColor,15,1,200),[1,3,2]);
                wbar([1,end],:,:) = 0;
                wbar(:,[1,end],:) = 0;
                app.DownloadlatestdataButton.Icon = wbar;
                
                drawnow;    % update graphics
                
                % download data from JHU github repository
                githubLink = 'https://github.com/CSSEGISandData/COVID-19/archive/master.zip';
                unzip(githubLink,'data');
                
                % loop and animate waitbar
                n = 20;
                for i = 1:n
                    % Update image data (royalblue)
                    currentProg = min(round((size(wbar,2)-2)*(i/n)),size(wbar,2)-2);
                    app.DownloadlatestdataButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                    app.DownloadlatestdataButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                    app.DownloadlatestdataButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                    % Pause to slow down animation
                    pause(.1)
                end
                
                % close menu app, open main app
                delete(app);
                main;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COVID-19 Pandemic';
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'The COVID-19 Pandemic';
            app.UseexistingdataButton = uibutton(app.UIFigure, 'state');
            app.UseexistingdataButton.ValueChangedFcn = createCallbackFcn(app, @useExistingData, true);
            app.UseexistingdataButton.Text = 'Use existing data';
            app.DownloadlatestdataButton = uibutton(app.UIFigure, 'state');
            app.DownloadlatestdataButton.ValueChangedFcn = createCallbackFcn(app, @updateData, true);
            app.DownloadlatestdataButton.Tooltip = {'will be retrieved from Johns Hopkins Data Repository'};
            app.DownloadlatestdataButton.Text = 'Download latest data';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'COVIDimage.jpg';
            app.NoexistingdataLabel = uilabel(app.UIFigure);
            app.NoexistingdataLabel.Text = 'No existing data';
            app.DATELabel = uilabel(app.UIFigure);
            app.DATELabel.Text = '[DATE]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end